classdef Eurus_Task1_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UpdateDemoButton              matlab.ui.control.Button
        ManualOFFButton               matlab.ui.control.Button
        ManualONButton                matlab.ui.control.Button
        BuzzerStatusLamp              matlab.ui.control.Lamp
        BuzzerStatusLampLabel         matlab.ui.control.Label
        MotorStatusLamp               matlab.ui.control.Lamp
        MotorStatusLampLabel          matlab.ui.control.Label
        GasADCEditField               matlab.ui.control.NumericEditField
        GasADCLabel_2                 matlab.ui.control.Label
        InputVoltageVEditField        matlab.ui.control.NumericEditField
        InputVoltageVEditFieldLabel   matlab.ui.control.Label
        ScriptedSimulationDemoNophysicalArduinoconnectedLabel  matlab.ui.control.Label
        EurusTask1MonitoringGUILabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ManualOFFButton, ManualONButton, 
        % ...and 1 other component
        function ButtonPushedFcn(app, event)
            % Read the scripted input values
            voltage = app.InputVoltageVEditField.Value;
            gasADC = app.GasADCEditField.Value;
        
            % Manual control
            if event.Source == app.ManualONButton
                app.ManualONButton.UserData = true;
        
            elseif event.Source == app.ManualOFFButton
                app.ManualONButton.UserData = false;
            end
        
            % Remember whether manual ON is active
            manualRequest = isequal(app.ManualONButton.UserData, true);
        
            % Automatic conditions
            automaticTrigger = (voltage >= 8) || (gasADC >= 600);
        
            % Same OR logic as the Arduino sketch
            outputON = automaticTrigger || manualRequest;
        
            % Update motor and buzzer lamps
            if outputON
                app.MotorStatusLamp.Color = [0.2 0.8 0.2];
                app.BuzzerStatusLamp.Color = [0.2 0.8 0.2];
            else
                app.MotorStatusLamp.Color = [0.85 0.2 0.2];
                app.BuzzerStatusLamp.Color = [0.85 0.2 0.2];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EurusTask1MonitoringGUILabel = uilabel(app.UIFigure);
            app.EurusTask1MonitoringGUILabel.Text = 'Eurus Task 1 Monitoring GUI';
            app.ScriptedSimulationDemoNophysicalArduinoconnectedLabel = uilabel(app.UIFigure);
            app.ScriptedSimulationDemoNophysicalArduinoconnectedLabel.Text = 'Scripted Simulation Demo — No physical Arduino connected';
            app.InputVoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.InputVoltageVEditFieldLabel.Text = 'Input Voltage (V)';
            app.InputVoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputVoltageVEditField.Value = 7;
            app.GasADCLabel_2 = uilabel(app.UIFigure);
            app.GasADCLabel_2.Text = 'Gas ADC ';
            app.GasADCEditField = uieditfield(app.UIFigure, 'numeric');
            app.GasADCEditField.Value = 512;
            app.MotorStatusLampLabel = uilabel(app.UIFigure);
            app.MotorStatusLampLabel.Text = 'Motor Status';
            app.MotorStatusLamp = uilamp(app.UIFigure);
            app.BuzzerStatusLampLabel = uilabel(app.UIFigure);
            app.BuzzerStatusLampLabel.Text = 'Buzzer Status';
            app.BuzzerStatusLamp = uilamp(app.UIFigure);
            app.ManualONButton = uibutton(app.UIFigure, 'push');
            app.ManualONButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushedFcn, true);
            app.ManualONButton.Text = 'Manual ON';
            app.ManualOFFButton = uibutton(app.UIFigure, 'push');
            app.ManualOFFButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushedFcn, true);
            app.ManualOFFButton.Text = 'Manual OFF';
            app.UpdateDemoButton = uibutton(app.UIFigure, 'push');
            app.UpdateDemoButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushedFcn, true);
            app.UpdateDemoButton.Text = 'Update Demo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Eurus_Task1_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end